classdef Speaker_Identification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ContraseabreteSsamoLabel    matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        SecondsEditFieldLabel       matlab.ui.control.Label
        SpeakerIdentificationLabel  matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        Image                       matlab.ui.control.Image
        ImportfileButton            matlab.ui.control.Button
        RecordButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        model = TrainModel(); % Se crea el modelo.
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.ImageSource = strcat('Imagenes/unknown.png');
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
             if app.EditField.Value > 0
                recObj = audiorecorder(48000,16,1,-1); % Creando el objeto de audio
            
                Fs = recObj.SampleRate; % Extrayendo la frecuencia de muestreo del objeto de grabación.
            
                set(app.Lamp, 'Color','green')
                recordblocking(recObj, app.EditField.Value);
                set(app.Lamp, 'Color','red')

            
                x= getaudiodata(recObj); % Extrayendo los datos grabados del elemento de grabación

                label = PredictionModel(app.model, x, Fs); % Prediciendo la categoría del audio.
                app.Image.ImageSource = strcat('Imagenes/',string(label),'.png');
                else
                    msgbox('Por favor ingrese un tiempo positivo :)');
             end
        end

        % Button pushed function: ImportfileButton
        function ImportfileButtonPushed(app, event)
            try
                % Obteniendo el archivo a ingresar.
                [filename, filepath] = uigetfile({'*.*';'*.mp3';'*.ogg';},'Busque un archivo de audio');
                [x,Fs] = audioread([filepath filename]); % Leyendo la señal de audio ingresada.
            
                label = PredictionModel(app.model, x, Fs) % Prediciendo la categoría del audio.
                app.Image.ImageSource = strcat('Imagenes/',string(label),'.png');
            catch 
                msgbox('Seleccione un archivo o grabe un audio')
            end       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Tag = 'rec';
            app.RecordButton.Text = 'Record';
            app.ImportfileButton = uibutton(app.UIFigure, 'push');
            app.ImportfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfileButtonPushed, true);
            app.ImportfileButton.Text = 'Import file';
            app.Image = uiimage(app.UIFigure);
            app.Image.Tag = 'image';
            app.Lamp = uilamp(app.UIFigure);
            app.SpeakerIdentificationLabel = uilabel(app.UIFigure);
            app.SpeakerIdentificationLabel.Text = {'Speaker Identification'; ''};
            app.SecondsEditFieldLabel = uilabel(app.UIFigure);
            app.SecondsEditFieldLabel.Text = 'Seconds';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 2;
            app.ContraseabreteSsamoLabel = uilabel(app.UIFigure);
            app.ContraseabreteSsamoLabel.Text = 'Contraseña: Ábrete Sésamo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Speaker_Identification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end